classdef QPSK_ASCII_App < matlab.apps.AppBase
    properties (Access = public)
        UIFigure matlab.ui.Figure
        MessageEditField matlab.ui.control.EditField
        EbN0EditField matlab.ui.control.NumericEditField
        RunSimulationButton matlab.ui.control.Button
        RecoveredMessageTextArea matlab.ui.control.TextArea
        BERValueLabel matlab.ui.control.Label
        BitErrorCountValueLabel matlab.ui.control.Label
        ConstellationAxes matlab.ui.control.UIAxes
    end

    properties (Access = private)
        StatusLabel matlab.ui.control.Label
    end

    methods (Access = private)
        function runSimulationButtonPushed(app, ~)
            app.runSimulation();
        end

        function createComponents(app)
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            cfg = qpsk_config();
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'QPSK ASCII通信演示系统';
            titleLabel = uilabel(app.UIFigure);
            titleLabel.Position = [28 568 984 34];
            titleLabel.Text = 'QPSK ASCII通信实验平台';
            titleLabel.FontSize = 24;
            titleLabel.FontWeight = 'bold';
            titleLabel.FontColor = [0.08 0.20 0.36];
            inputPanel = uipanel(app.UIFigure);
            inputPanel.Position = [28 28 300 520];
            inputPanel.Title = '仿真控制与结果显示';
            inputPanel.FontWeight = 'bold';
            inputPanel.BackgroundColor = [1 1 1];
            messageLabel = uilabel(inputPanel);
            messageLabel.Position = [20 450 250 22];
            messageLabel.Text = '输入ASCII消息';
            messageLabel.FontWeight = 'bold';
            app.MessageEditField = uieditfield(inputPanel, 'text');
            app.MessageEditField.Value = cfg.message;
            app.MessageEditField.Tooltip = ...
                '请输入包含 7 位 ASCII 字符的非空消息。';
            ebnoLabel = uilabel(inputPanel);
            ebnoLabel.Position = [20 365 250 22];
            ebnoLabel.Text = '信噪比 Eb/N0（dB）';
            ebnoLabel.FontWeight = 'bold';
            app.EbN0EditField = uieditfield(inputPanel, 'numeric');
            app.EbN0EditField.Value = cfg.functionalEbNoDb;
            app.EbN0EditField.Limits = [-Inf Inf];
            app.RunSimulationButton = uibutton(inputPanel, 'push');
            app.RunSimulationButton.Text = '开始仿真';
            app.RunSimulationButton.ButtonPushedFcn = ...
                createCallbackFcn(app, @runSimulationButtonPushed, true);
            recoveredLabel = uilabel(inputPanel);
            recoveredLabel.Position = [20 225 250 22];
            recoveredLabel.Text = '恢复ASCII消息';
            recoveredLabel.FontWeight = 'bold';
            app.RecoveredMessageTextArea = uitextarea(inputPanel);
            app.RecoveredMessageTextArea.Editable = 'off';
            app.RecoveredMessageTextArea.Value = {''};
            berLabel = uilabel(inputPanel);
            berLabel.Position = [20 115 80 22];
            berLabel.Text = '误码率 BER';
            berLabel.FontWeight = 'bold';
            app.BERValueLabel = uilabel(inputPanel);
            app.BERValueLabel.Text = '--';
            bitErrorLabel = uilabel(inputPanel);
            bitErrorLabel.Position = [20 80 100 22];
            bitErrorLabel.Text = '误码个数';
            bitErrorLabel.FontWeight = 'bold';
            app.BitErrorCountValueLabel = uilabel(inputPanel);
            app.BitErrorCountValueLabel.Text = '--';
            app.StatusLabel = uilabel(inputPanel);
            app.StatusLabel.Text = '就绪';
            app.ConstellationAxes = uiaxes(app.UIFigure);
            app.ConstellationAxes.XLabel.String = '同相分量';
            app.ConstellationAxes.YLabel.String = '正交分量';
            app.ConstellationAxes.Title.String = ...
                '接收QPSK星座图';
            app.UIFigure.Visible = 'on';
        end
    end

    methods (Access = public)
        function runSimulation(app)
            message = app.MessageEditField.Value;
            if isempty(message)
                uialert(app.UIFigure, ...
                    '请输入至少一个 ASCII 字符。', '消息无效');
                return;
            end

            app.RunSimulationButton.Enable = 'off';
            app.StatusLabel.Text = '仿真运行中…';
            app.StatusLabel.FontColor = [0.45 0.35 0.10];
            drawnow limitrate;

            try
                cfg = qpsk_config();
                bits = ascii_to_bits(message);
                link = simulate_qpsk_link(bits, app.EbN0EditField.Value, cfg);

                app.RecoveredMessageTextArea.Value = ...
                    {bits_to_ascii(link.rxBits)};
                app.BERValueLabel.Text = sprintf('%.6g', link.ber);
                app.BitErrorCountValueLabel.Text = ...
                    sprintf('%d', link.bitErrors);

                cla(app.ConstellationAxes);
                scatter(app.ConstellationAxes, real(link.rxSymbols), ...
                    imag(link.rxSymbols), 34, [0.05 0.45 0.75], 'filled');
                grid(app.ConstellationAxes, 'on');
                axis(app.ConstellationAxes, 'equal');
                app.ConstellationAxes.XLim = [-2 2];
                app.ConstellationAxes.YLim = [-2 2];
                app.ConstellationAxes.Title.String = sprintf( ...
                    '接收QPSK星座图（Eb/N0 = %g dB）', ...
                    app.EbN0EditField.Value);

                app.StatusLabel.Text = '仿真完成';
                app.StatusLabel.FontColor = [0.12 0.48 0.22];
            catch exception
                app.StatusLabel.Text = '仿真失败';
                app.StatusLabel.FontColor = [0.75 0.15 0.12];
                uialert(app.UIFigure, '仿真失败，请检查输入消息和信噪比参数。', '仿真错误');
            end

            app.RunSimulationButton.Enable = 'on';
        end

        function app = QPSK_ASCII_App()
            appFolder = fileparts(mfilename('fullpath'));
            addpath(fullfile(fileparts(appFolder), 'src'));
            createComponents(app);
            registerApp(app, app.UIFigure);

            if nargout == 0
                clear app
            end
        end

        function delete(app)
            if ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                delete(app.UIFigure);
            end
        end
    end
end